classdef ClassDevAppOther < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        ComponentTab       matlab.ui.container.Tab
        PropertiesPanel    matlab.ui.container.Panel
        GridLayout         matlab.ui.container.GridLayout
        AddPropertyButton  matlab.ui.control.Button
        PropertyLabel      matlab.ui.control.Label
        TabGroup2          matlab.ui.container.TabGroup
        VerticesTab        matlab.ui.container.Tab
        EdgesTab           matlab.ui.container.Tab
        DefaultValueLabel  matlab.ui.control.Label
        GraphTab           matlab.ui.container.Tab
    end

    
    properties (Access = public)
        Property % Description
    end
    
    methods (Access = public)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddPropertyButton
        function AddPropertyButtonPushed(app, event)
        app.GridLayout.RowHeight  = [app.GridLayout.RowHeight, 20];
        app.AddPropertyButton.Layout.Row = app.AddPropertyButton.Layout.Row+1;
        FieldS = uieditfield(app.GridLayout);
        FieldS.Layout.Row = app.AddPropertyButton.Layout.Row-1;
        FieldS.Layout.Column = 2;
        FieldN = uieditfield(app.GridLayout,'numeric');
        FieldN.Layout.Row = app.AddPropertyButton.Layout.Row-1;
        FieldN.Layout.Column = 3;
        DeleteButton = uibutton(app.GridLayout,'Text','Delete Property');
        DeleteButton.Layout.Row = app.AddPropertyButton.Layout.Row-1;
        DeleteButton.Layout.Column = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ComponentTab = uitab(app.TabGroup);
            app.ComponentTab.Title = 'Component';
            app.PropertiesPanel = uipanel(app.ComponentTab);
            app.PropertiesPanel.Title = 'Properties';
            app.GridLayout = uigridlayout(app.PropertiesPanel);
            app.AddPropertyButton = uibutton(app.GridLayout, 'push');
            app.AddPropertyButton.ButtonPushedFcn = createCallbackFcn(app, @AddPropertyButtonPushed, true);
            app.AddPropertyButton.Text = 'Add Property';
            app.PropertyLabel = uilabel(app.GridLayout);
            app.PropertyLabel.Text = 'Property';
            app.DefaultValueLabel = uilabel(app.GridLayout);
            app.DefaultValueLabel.Text = 'Default Value';
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.TabGroup2 = uitabgroup(app.GraphTab);
            app.VerticesTab = uitab(app.TabGroup2);
            app.VerticesTab.Title = 'Vertices';
            app.EdgesTab = uitab(app.TabGroup2);
            app.EdgesTab.Title = 'Edges';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClassDevAppOther

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end